classdef FM2D_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        ProgressText        matlab.ui.control.Label
        VisualizeButton     matlab.ui.control.Button
        uipanel1            matlab.ui.container.Panel
        StartButton         matlab.ui.control.Button
        OpenButton          matlab.ui.control.Button
        text1               matlab.ui.control.Label
        SaveAsButton        matlab.ui.control.Button
        ScopeButton         matlab.ui.control.Button
        GetperiodButton     matlab.ui.control.Button
        RunModelButton      matlab.ui.control.Button
        status1b            matlab.ui.control.Label
        status1             matlab.ui.control.Label
        status2b            matlab.ui.control.Label
        status2             matlab.ui.control.Label
        BuildtclfileButton  matlab.ui.control.Button
        AnalysisButton      matlab.ui.control.Button
        BuildingButton      matlab.ui.control.Button
        status0b            matlab.ui.control.Label
        status0             matlab.ui.control.Label
        ModelButton         matlab.ui.control.Button
        Image2              matlab.ui.control.Image
        ResetButton         matlab.ui.control.Button
        AboutButton         matlab.ui.control.Button
        status0_2           matlab.ui.control.Label
        DevelopersButton    matlab.ui.control.Button
        RegisterButton      matlab.ui.control.Button
        HelpButton          matlab.ui.control.Button
        Image               matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function BLINK_TEXT(app, YourString, pauseTime, nTimes)
            app.ProgressText.FontColor=[0 1 0];
            app.ProgressText.Text=YourString;
            drawnow();
            
            for i=1:nTimes
                app.ProgressText.Text='';
                drawnow();
                pause(pauseTime);
                app.ProgressText.Text=YourString;
                drawnow();
                pause(pauseTime);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FM2D_OpeningFcn(app, varargin)
            global MainDirectory
            clc; clear ProjectPath ProjectName;
            MainDirectory=pwd;
            
            app.text1.Text                  = 'Project: --';
            
            app.StartButton.Enable          = 'on';
            app.OpenButton.Enable           = 'on';
            app.SaveAsButton.Enable         = 'off';
            app.ScopeButton.Enable          = 'off';
            
            app.ModelButton.Enable          = 'off';
            app.BuildingButton.Enable       = 'off';
            app.AnalysisButton.Enable       = 'off';
            
            app.GetperiodButton.Enable      = 'off';
            app.BuildtclfileButton.Enable   = 'off';
            app.RunModelButton.Enable       = 'off';
            app.VisualizeButton.Enable      = 'off';
            
            app.status0.BackgroundColor  = 'r';
            app.status0.FontColor        = 'w';
            app.status0.Text             = 'status: not defined';
            app.status0b.BackgroundColor = 'r';
            app.status1.BackgroundColor  = 'r';
            app.status1.FontColor        = 'w';
            app.status1.Text             = 'status: not defined';
            app.status1b.BackgroundColor = 'r';
            app.status2.BackgroundColor  = 'r';
            app.status2.FontColor        = 'w';
            app.status2.Text             = 'status: not defined';
            app.status2b.BackgroundColor = 'r';

            app.Image.Visible='off';
            
            BLINK_TEXT (app,'© A. Elkady, University of Southampton 2020', 0.2, 2);
            
        end

        % Button pushed function: AnalysisButton
        function AnalysisButton_Callback(app, event)
            Analysis_App(app);
        end

        % Button pushed function: BuildingButton
        function BuildingButton_Callback(app, event)
            global FrameType
            if FrameType==1 || FrameType==4
                Build_MRF_App(app);
            else
                Build_CBF_App(app);
            end
        end

        % Button pushed function: OpenButton
        function OpenButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName Units FrameType
            clc;
            
            % Get current directory, the user-specified project name, and selected Units
            MainDirectory=pwd;
            [ProjectName,ProjectPath] = uigetfile('*.mat','Select the Project .mat file');
            
            cd(MainDirectory)
            
            % Get current directory and user-specified project name
            if exist(ProjectPath) ==0
                d=errordlg('Project does not exist in current directory!','Error','modal');
                uiwait(d);
            else
                cd (ProjectPath)
                save(ProjectName,'MainDirectory','ProjectPath','ProjectName','-append');
                pause(1)
                load(ProjectName);
                cd (MainDirectory)
                
                if exist(RFpath,'dir')==0;       RFpath=ProjectPath; end
                if strcmp(RFpath(end),'\')==1; RFpath(end)=[]; end
                
                cd (ProjectPath)
                save(ProjectName,'RFpath','-append');
                cd (MainDirectory)
                
                if EQ==1 && exist(GMFolderPath,'dir')==0; warndlg('The GM folder path does not exist! Please ammend from the Analysis Parameters module'); end
                
                app.Image.Visible                   = 'off';
                app.SaveAsButton.Enable             = 'on';
                app.ModelButton.Enable              = 'on';
                app.ScopeButton.Enable              = 'on';
                
                if Modelstatus==1
                    if ModelELOption==1; s0='Nonlinear-'; else s0='Linear-'; end
                    if CompositeX==1; s1='C'; else s1='B'; end
                    if GFX==1;        s2='G'; else s2='';  end
                    Typestr=[s0,s1,s2];
                    
                    app.status0.BackgroundColor     = 'g';
                    app.status0.FontColor           = 'k';
                    app.status0.Text                = Typestr;
                    app.status0b.BackgroundColor    = 'g';
                    app.BuildingButton.Enable       = 'on';
                end
                
                if Definestatus==1
                    app.status1.BackgroundColor  = 'g';
                    app.status1.FontColor='k';
                    if FrameType==1
                        app.status1.Text=[num2str(NStory),'-story MRF'];
                    elseif FrameType==2
                        if BraceLayout==1; app.status1.Text=[num2str(NStory),'-story CBF - X-Brace']; end
                        if BraceLayout==2; app.status1.Text=[num2str(NStory),'-story CBF - Chevron']; end
                    elseif FrameType==3
                        app.status1.Text=[num2str(NStory),'-story EBF'];
                    elseif FrameType==4
                        app.status1.Text=[num2str(NStory),'-story RC MRF'];
                    end
                    app.status1b.BackgroundColor    = 'g';
                    app.AnalysisButton.Enable       = 'on';
                    app.GetperiodButton.Enable      = 'on';
                    app.BuildtclfileButton.Enable   = 'on';
                end
                
                if Analysisstatus==1
                    app.status2.BackgroundColor  = 'g';
                    app.status2.FontColor        = 'k';
                    if IDA==1;              app.status2.Text=['Dynamic: IDA'];           end
                    if Dynamic_TargetSA==1; app.status2.Text=['Dynamic: Target Sa'];     end
                    if Dynamic_TargetSF==1; app.status2.Text=['Dynamic: Target SF'];     end
                    if PO==1;               app.status2.Text=['Pushover Analysis'];      end
                    if EV==1;               app.status2.Text=['Eigenvalue Analysis'];    end
                    if ELF==1;              app.status2.Text=['ELF Analysis'];           end
                    app.status2b.BackgroundColor = 'g';
                    app.RunModelButton.Enable    = 'on';
                end
                
                if Runstatus==1
                    app.VisualizeButton.Enable = 'on';
                else
                    app.VisualizeButton.Enable = 'off';
                end
                
                if exist('zetaSA')==0
                    zetaSA=0.05;
                    cd (ProjectPath)
                    save(ProjectName,'zetaSA','-append');
                    cd (MainDirectory)
                end
                
                if exist('EBF_W')==0
                    EBF_W                = 0;    
                    cd (ProjectPath)
                    save(ProjectName,'EBF_W','-append');
                    cd (MainDirectory)
                end

                if exist('cLoad_status')==0
                    cLoad_status                = 1;    
                    cd (ProjectPath)
                    save(ProjectName,'cLoad_status','-append');
                    cd (MainDirectory)
                end
                      
                if exist('MF_SL')==0
                    MF_SL                = 0;    
                    cd (ProjectPath)
                    save(ProjectName,'MF_SL','-append');
                    cd (MainDirectory)
                end
                                
                if FrameType~=4
                    Ec                = 0;    
                    Er                = 0;   
                    fc                = 0;
                    fyR               = 0;
                    muC               = 0;
                    muR               = 0;
                    cd (ProjectPath)
                    save(ProjectName,'Ec','Er','fc','fyR','muC','muR','-append');
                    cd (MainDirectory)
                end
                                
            end
            
            xx=ProjectName;
            xx=xx(1:end-4);
            app.text1.Text=['Project: ',xx];
        end

        % Button pushed function: SaveAsButton
        function SaveAsButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName MainDirectory2 ProjectPath2 ProjectName2
            clc;
            MainDirectory2=pwd;
            try
            cd (ProjectPath);
            load (ProjectName);
            cd (MainDirectory);
            
            % Get current directory, the user-specified project name, and selected Units
            [ProjectName2, ProjectPath2] = uiputfile('*.mat','Save current project as');
            
            xx=ProjectName2;
            xx=xx(1:end-4);
            xx1= xx(~isspace(xx)); % remove spaces from project name to get opensees file name
            OpenSEESFileName = [xx1,'.tcl'];

            cd (ProjectPath2);
            save(ProjectName2, '-regexp', '^(?!(event|app)$).');
            cd (MainDirectory2);
            
            MainDirectory = MainDirectory2;
            ProjectName   = ProjectName2;
            ProjectPath   = ProjectPath2;
            
            clear MainDirectory2  ProjectName2  ProjectPath2
            
            cd (ProjectPath);
            save(ProjectName,'MainDirectory','ProjectName','ProjectPath','-append');
            cd (MainDirectory);
            
            xx=ProjectName;
            xx=xx(1:end-4);
            app.text1.Text=['Project: ',xx];
            catch ME
                
            end
        end

        % Button pushed function: StartButton
        function StartButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath Units FrameType BuildingDescription
            clc;
            
            FM2D_OpeningFcn(app);
            
            MainDirectory=pwd;
            
            [ProjectName, ProjectPath] = uiputfile('*.mat','Select the workspace direcotry and specify project name');
            
            if  isempty(ProjectName) || ischar(ProjectName)==0; return; end
            
            Units=0;
            FrameType=0;
            waitfor(TypeMessage_App(app));
            
            if Units==0 || FrameType==0; return; end
            
            Initialize_Project(MainDirectory, ProjectPath, ProjectName, Units, FrameType, BuildingDescription);
            
            xx=ProjectName;
            xx=xx(1:end-4);
            app.text1.Text=['Project: ',xx];

            app.SaveAsButton.Enable         = 'on';
            app.ScopeButton.Enable          = 'on';
            app.ModelButton.Enable          = 'on';
            app.Image.Visible               = 'off';
            
        end

        % Button pushed function: VisualizeButton
        function VisualizeButton_Callback(app, event)
            Visualize_App(app)
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath FrameType ExecutionOption
            clc; fclose all;
            cd(ProjectPath)
            load(ProjectName)
            cd(MainDirectory)
            
            StartTime=tic;    % Start Watch for Timing the Analysis Run Time
            app.Image.Visible      = 'on';
            app.ProgressText.FontColor=[1 1 0];
            
            if Runstatus==1; waitfor(ExecutionMessage_App(app)); end
            
            try
                Execution_Commands(app, FrameType, ExecutionOption, OpenSEESFileName, RFpath, EV, PO, EQ, ELF, PM_Option, BraceLayout);
                
                Runstatus=1;
                
                cd (ProjectPath)
                save(ProjectName,'Runstatus','-append')
                cd (MainDirectory)
                
                app.VisualizeButton.Enable = 'on';
                app.Image.Visible          = 'off';
                
                ElapsedTime = toc(StartTime);
                app.ProgressText.FontColor=[0 1 0];
                app.ProgressText.Text=['DONE! Analysis time is ',num2str(round((ElapsedTime/60.0)*10)/10),' minutes']; drawnow;
                
            catch ME
                cd(MainDirectory)
                write_Error(ME);
                app.ProgressText.FontColor=[1 0 0];
                app.ProgressText.Text=['Error has occured! Please see Error file!']; drawnow;
                app.Image.Visible          = 'off';
            end
            
            
        end

        % Button pushed function: GetperiodButton
        function GetperiodButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            clc; fclose all;
            cd(ProjectPath)
            load(ProjectName)
            cd(MainDirectory)
            
            BuildOption=1;
            cd (ProjectPath)
            save(ProjectName,'BuildOption','-append')
            cd (MainDirectory)
            
            if Units==1
                massUnit=' kN.sec2/mm';
                forceUnit=' kN';
            else
                massUnit=' kip.sec2/in';
                forceUnit=' kip';
            end
            
            app.Image.Visible      = 'on';
            app.ProgressText.FontColor=[0 1 0];
            
            try
                
                [T1_computed,MassTot,Ws] = Get_Mass_and_Period();
                
                fclose all;
                delete ('TempModel.tcl');
                
                app.ProgressText.Text=(['T1=',num2str(round(T1_computed*100)/100),'sec','   ','Mass=',num2str(round(MassTot*100)/100),massUnit,'   ','Ws=',num2str(round(Ws)),forceUnit]);
                drawnow;
                app.Image.Visible          = 'off';
            catch ME
                cd(MainDirectory)
                write_Error(ME);
                app.ProgressText.FontColor=[1 0 0];
                app.ProgressText.Text=['Error has occured! Please see Error file!']; drawnow;
                app.Image.Visible          = 'off';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clc; fclose all;
            app.Image.Visible='off';
            FM2D_OpeningFcn(app)
            BLINK_TEXT (app,'© A. Elkady, University of Southampton 2020', 0.2, 2)
        end

        % Button pushed function: ScopeButton
        function ScopeButtonPushed(app, event)
            %Show_Project_Summary;
            [SummaryText]=Get_Project_Summary;
            Project_Summary_App(SummaryText);
        end

        % Button pushed function: BuildtclfileButton
        function BuildtclfileButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            
            try
                app.ProgressText.FontColor=[0 1 0];
                app.ProgressText.Text='Creating tcl File...'; drawnow;
                
                BuildOption=1;
                cd (ProjectPath);
                load(ProjectName,'OpenSEESFileName','FrameType');
                save(ProjectName,'BuildOption','-append');
                cd (MainDirectory);
                
                AnalysisTypeID=3;
                if FrameType==1 || FrameType==4 
                    CREATOR_MODEL_MRF(AnalysisTypeID,0);
                else
                    CREATOR_MODEL_CBF(AnalysisTypeID,0);
                end
                CREATOR_ANALYSIS (AnalysisTypeID,0);
                
                copyfile(OpenSEESFileName,ProjectPath,'f');
                
                app.ProgressText.Text  = 'Tcl file is created; check project directory!'; drawnow;
            catch ME
                cd(MainDirectory)
                write_Error(ME);
                app.ProgressText.FontColor=[1 0 0];
                app.ProgressText.Text=['Error has occured! Please see Error file!']; drawnow;
                app.Image.Visible          = 'off';
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            About_App;
        end

        % Button pushed function: ModelButton
        function ModelButtonPushed(app, event)
            Model_App(app);
        end

        % Button pushed function: DevelopersButton
        function DevelopersButtonPushed(app, event)
            Developers_App;
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            web('https://forms.gle/qLNwgGFS5vbCxmHB7','-new');
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            global MainDirectory
            cd (MainDirectory)
            filex='FM-2D Manual.pdf';
            if ispc;  winopen(filex);           end
            if ismac; system(['open ' filex ]); end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'FM-2D Main Console';
            app.figure1.Tag = 'figure1';
            app.ProgressText = uilabel(app.figure1);
            app.ProgressText.Tag = 'text2';
            app.ProgressText.Text = '© Ahmed Elkady, University of Southampton, 2020';
            app.VisualizeButton = uibutton(app.figure1, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButton_Callback, true);
            app.VisualizeButton.Tag = 'VisualizeButton';
            app.VisualizeButton.Enable = 'off';
            app.VisualizeButton.Text = 'Visulaize';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.StartButton = uibutton(app.uipanel1, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButton_Callback, true);
            app.StartButton.Tag = 'StartButton';
            app.StartButton.Tooltip = 'Start a new project';
            app.StartButton.Text = 'New';
            app.OpenButton = uibutton(app.uipanel1, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_Callback, true);
            app.OpenButton.Tag = 'OpenButton';
            app.OpenButton.Tooltip = 'Open an existing project';
            app.OpenButton.Text = 'Open';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text27';
            app.text1.Text = 'Project: --';
            app.SaveAsButton = uibutton(app.uipanel1, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButton_Callback, true);
            app.SaveAsButton.Tag = 'SaveAsButton';
            app.SaveAsButton.Enable = 'off';
            app.SaveAsButton.Tooltip = 'Save current project under a different name';
            app.SaveAsButton.Text = 'Save as';
            app.ScopeButton = uibutton(app.uipanel1, 'push');
            app.ScopeButton.ButtonPushedFcn = createCallbackFcn(app, @ScopeButtonPushed, true);
            app.ScopeButton.Icon = 'scope.png';
            app.ScopeButton.Tooltip = 'Show summary of the defined project paramters';
            app.ScopeButton.Text = 'Scope';
            app.GetperiodButton = uibutton(app.figure1, 'push');
            app.GetperiodButton.ButtonPushedFcn = createCallbackFcn(app, @GetperiodButtonPushed, true);
            app.GetperiodButton.Tag = 'DefineModelButton';
            app.GetperiodButton.Enable = 'off';
            app.GetperiodButton.Text = 'Get period';
            app.RunModelButton = uibutton(app.figure1, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Tag = 'DefineModelButton';
            app.RunModelButton.Enable = 'off';
            app.RunModelButton.Text = 'Run Model';
            app.status1b = uilabel(app.figure1);
            app.status1b.Text = '';
            app.status1 = uilabel(app.figure1);
            app.status1.Text = 'status: not defined';
            app.status2b = uilabel(app.figure1);
            app.status2b.Text = '';
            app.status2 = uilabel(app.figure1);
            app.status2.Text = 'status: not defined';
            app.BuildtclfileButton = uibutton(app.figure1, 'push');
            app.BuildtclfileButton.ButtonPushedFcn = createCallbackFcn(app, @BuildtclfileButtonPushed, true);
            app.BuildtclfileButton.Tag = 'DefineModelButton';
            app.BuildtclfileButton.Enable = 'off';
            app.BuildtclfileButton.Text = 'Build tcl file';
            app.AnalysisButton = uibutton(app.figure1, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButton_Callback, true);
            app.AnalysisButton.Tag = 'AnalysisButton';
            app.AnalysisButton.Text = {'Analysis'; 'Parameters'};
            app.BuildingButton = uibutton(app.figure1, 'push');
            app.BuildingButton.ButtonPushedFcn = createCallbackFcn(app, @BuildingButton_Callback, true);
            app.BuildingButton.Tag = 'DefineModelButton';
            app.BuildingButton.Text = {'Building'; 'Parameters'};
            app.status0b = uilabel(app.figure1);
            app.status0b.Text = '';
            app.status0 = uilabel(app.figure1);
            app.status0.Text = 'status: not defined';
            app.ModelButton = uibutton(app.figure1, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonPushed, true);
            app.ModelButton.Tag = 'DefineModelButton';
            app.ModelButton.Text = {'Model'; 'Parameters'};
            app.Image2 = uiimage(app.figure1);
            app.Image2.ImageSource = 'Banner.jpg';
            app.ResetButton = uibutton(app.figure1, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset'};
            app.ResetButton.Text = 'R';
            app.AboutButton = uibutton(app.figure1, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Tooltip = {''};
            app.AboutButton.Text = 'about';
            app.status0_2 = uilabel(app.figure1);
            app.status0_2.Text = 'v1.2204';
            app.DevelopersButton = uibutton(app.figure1, 'push');
            app.DevelopersButton.ButtonPushedFcn = createCallbackFcn(app, @DevelopersButtonPushed, true);
            app.DevelopersButton.Text = 'developers';
            app.RegisterButton = uibutton(app.figure1, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'register';
            app.HelpButton = uibutton(app.figure1, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {''};
            app.HelpButton.Text = 'help';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'Transparent.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FM2D_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)FM2D_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end