classdef Visualize_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        text3       matlab.ui.control.Label
        CB1         matlab.ui.control.CheckBox
        CB2         matlab.ui.control.CheckBox
        CB3         matlab.ui.control.CheckBox
        CB4         matlab.ui.control.CheckBox
        PlotButton  matlab.ui.control.Button
        text7       matlab.ui.control.Label
        text8       matlab.ui.control.Label
        edit1       matlab.ui.control.NumericEditField
        edit2       matlab.ui.control.NumericEditField
        CB6         matlab.ui.control.CheckBox
        CB7         matlab.ui.control.CheckBox
        CB8         matlab.ui.control.CheckBox
        CB11        matlab.ui.control.CheckBox
        text7_2     matlab.ui.control.Label
        text7_3     matlab.ui.control.Label
        text7_4     matlab.ui.control.Label
        text7_5     matlab.ui.control.Label
        CB13        matlab.ui.control.CheckBox
        edit100     matlab.ui.control.EditField
        CB14        matlab.ui.control.CheckBox
        EDPtype     matlab.ui.control.DropDown
        EDPtype2    matlab.ui.control.DropDown
        EDPtype3    matlab.ui.control.DropDown
        EDPtype4    matlab.ui.control.DropDown
        text7_6     matlab.ui.control.Label
        editRi      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Visualize_Options_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global MainDirectory ProjectName ProjectPath FrameType
            clc;
            cd(ProjectPath)
            load(ProjectName,'GM_Start','GM_Last','PO','EQ','EV','IDA','ELF','Recorders','Delete_Flag','nRealizations')
            cd(MainDirectory)
            
            nGMs = GM_Last-GM_Start+1;
            %             app.edit1.Limits=[1 max(nGMs,2)];
            %             app.edit2.Limits=[1 max(nGMs,2)];
            
            if (PO==1 || ELF==1) && Delete_Flag==0
                app.CB1.Enable='on';
                app.CB2.Enable='on';
                app.CB3.Enable='on';
            end
            
            if EQ==1 && Delete_Flag==0
                app.CB1.Enable='on';
                app.CB2.Enable='on';
                app.CB4.Enable='on';
                app.CB6.Enable='on';
                app.CB13.Enable='on';
            end
            
            if IDA==1
                app.CB7.Enable='on';
                app.CB11.Enable='on';
            end
            
            app.CB8.Enable='on';
            
            if EQ~=1
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit1.Value=1;
                app.edit2.Value=1;
            else
                app.edit1.Value=GM_Start;
                app.edit2.Value=GM_Last;
            end
            
            if Recorders.Disp~=1
                app.CB2.Enable='off';
            end
            
            if nRealizations>1
                app.CB14.Enable='on';
                app.editRi.Enable='on';
                app.editRi.Limits=[1 nRealizations];
            end
            
            if FrameType==3
                app.CB6.Enable='off';               
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global MainDirectory  ProjectPath ProjectName
            clc
            cd (ProjectPath)
            load(ProjectName,'PO','EQ','Recorders','nRealizations');
            cd (MainDirectory)
            
            app.edit100.FontColor = [1.0 1.0 0.0];
            app.edit100.Value     = 'Please wait while data is processed.'; drawnow;
            
            GMi = app.edit1.Value;
            GMj = app.edit2.Value;
            nGMs = GMj-GMi+1;
            
            Ri = app.editRi.Value;
            
            
            try
                
                if app.CB8.Value==1
                    Plot_Eigen_Vectors()
                end
                
                if app.CB1.Value==1
                    if EQ==1
                        for GM_No=GMi:GMj
                            Plot_BSF_vs_SDR1(GM_No, Ri)
                        end
                    else
                        Plot_BSF_vs_SDR1(1, Ri)
                    end
                end
                
                if app.CB2.Value==1
                    if EQ==1
                        for GM_No=GMi:GMj
                            Plot_BSF_vs_RDR(GM_No, Ri)
                        end
                    else
                        Plot_BSF_vs_RDR(1, Ri)
                    end
                end
                
                if app.CB3.Value==1
                    Plot_Profile_Drift
                end
                
                if app.CB4.Value==1
                    EDP_selected=app.EDPtype.Value;
                    if EDP_selected=='1' && Recorders.SDR==1;    Plot_Profile_SDR(GMi,GMj); end
                    if EDP_selected=='2' && Recorders.SDR==1;    Plot_Profile_RDR(GMi,GMj); end
                    if EDP_selected=='3' && Recorders.RFA==1;    Plot_Profile_PFA(GMi,GMj); end
                    if EDP_selected=='4' && Recorders.RFA==1;    Plot_Profile_PFV(GMi,GMj); end
                    if EDP_selected=='5' && Recorders.Column==1; Plot_Profile_SSF(GMi,GMj); end
                end
                
                if app.CB7.Value==1
                    EDP_selected=app.EDPtype4.Value;
                    if EDP_selected=='1' && Recorders.SDR==1; Plot_IDA(GMi,GMj,'SDR'); end
                    if EDP_selected=='2' && Recorders.SDR==1; Plot_IDA(GMi,GMj,'RDR'); end
                    if EDP_selected=='3' && Recorders.RFA==1; Plot_IDA(GMi,GMj,'PFA'); end
                end
                
                if app.CB11.Value==1
                    Plot_Collapse_Fragility(GMi,GMj)
                end
                
                if app.CB13.Value==1 && nGMs*nRealizations>=3
                    EDP_selected=app.EDPtype3.Value;
                    if EDP_selected=='1' && Recorders.SDR==1; Plot_EDP_Fragility(GMi,GMj,'SDR'); end
                    if EDP_selected=='2' && Recorders.SDR==1; Plot_EDP_Fragility(GMi,GMj,'RDR'); end
                    if EDP_selected=='3' && Recorders.RFA==1; Plot_EDP_Fragility(GMi,GMj,'PFA'); end
                end
                
                if app.CB14.Value==1 && nGMs*nRealizations>=3
                    EDP_selected=app.EDPtype2.Value;
                    if EDP_selected=='1' && Recorders.SDR==1; Plot_EDP_vs_Realization(GMi,GMj,'SDR'); end
                    if EDP_selected=='2' && Recorders.SDR==1; Plot_EDP_vs_Realization(GMi,GMj,'RDR'); end
                    if EDP_selected=='3' && Recorders.RFA==1; Plot_EDP_vs_Realization(GMi,GMj,'PFA'); end
                end
                
                if app.CB6.Value==1
                    Visualize_Local_App(app)
                end
                
                app.edit100.FontColor = [0.0 1.0 0.0];
                app.edit100.Value     = 'Plot generated!'; drawnow;
                
            catch ME
                cd(MainDirectory)
                write_Error(ME)
                app.edit100.FontColor = [1.0 0.0 0.0];
                app.edit100.Value     = 'Error occurred. See Error file!'; drawnow;
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app);
        end

        % Value changed function: CB4
        function CB4ValueChanged(app, event)
            if app.CB4.Value==1
                app.EDPtype.Enable='on';
            else
                app.EDPtype.Enable='off';
            end
        end

        % Value changed function: CB14
        function CB14ValueChanged(app, event)
            if app.CB14.Value==1
                app.EDPtype2.Enable='on';
            else
                app.EDPtype2.Enable='off';
            end
        end

        % Value changed function: CB7
        function CB7ValueChanged(app, event)
            if app.CB7.Value==1
                app.EDPtype4.Enable='on';
            else
                app.EDPtype4.Enable='off';
            end
        end

        % Value changed function: CB13
        function CB13ValueChanged(app, event)
            if app.CB13.Value==1
                app.EDPtype3.Enable='on';
            else
                app.EDPtype3.Enable='off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Visualize_Options';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.Panel = uipanel(app.figure1);
            app.text3 = uilabel(app.Panel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Visualization Options';
            app.CB1 = uicheckbox(app.Panel);
            app.CB1.Enable = 'off';
            app.CB1.Text = 'Base shear force vs 1st-story drift ratio';
            app.CB2 = uicheckbox(app.Panel);
            app.CB2.Enable = 'off';
            app.CB2.Text = 'Base shear force vs Roof drift ratio';
            app.CB3 = uicheckbox(app.Panel);
            app.CB3.Enable = 'off';
            app.CB3.Text = 'Pushover floor displacement';
            app.CB4 = uicheckbox(app.Panel);
            app.CB4.ValueChangedFcn = createCallbackFcn(app, @CB4ValueChanged, true);
            app.CB4.Enable = 'off';
            app.CB4.Text = 'Profile plot';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tag = 'BuildButton';
            app.PlotButton.Text = 'Plot';
            app.text7 = uilabel(app.Panel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Ground motion records to plot';
            app.text8 = uilabel(app.Panel);
            app.text8.Tag = 'text8';
            app.text8.Text = 'to';
            app.edit1 = uieditfield(app.Panel, 'numeric');
            app.edit1.Limits = [1 Inf];
            app.edit1.ValueDisplayFormat = '%.0f';
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.Panel, 'numeric');
            app.edit2.Limits = [1 Inf];
            app.edit2.ValueDisplayFormat = '%.0f';
            app.edit2.Value = 44;
            app.CB6 = uicheckbox(app.Panel);
            app.CB6.Enable = 'off';
            app.CB6.Text = 'Local member response';
            app.CB7 = uicheckbox(app.Panel);
            app.CB7.ValueChangedFcn = createCallbackFcn(app, @CB7ValueChanged, true);
            app.CB7.Enable = 'off';
            app.CB7.Text = 'IDA curves';
            app.CB8 = uicheckbox(app.Panel);
            app.CB8.Enable = 'off';
            app.CB8.Text = 'Eigen Vectors';
            app.CB11 = uicheckbox(app.Panel);
            app.CB11.Enable = 'off';
            app.CB11.Text = 'Collapse fragility Curve';
            app.text7_2 = uilabel(app.Panel);
            app.text7_2.Tag = 'text7';
            app.text7_2.Text = 'Profile Plots';
            app.text7_3 = uilabel(app.Panel);
            app.text7_3.Tag = 'text7';
            app.text7_3.Text = 'IDA Plots';
            app.text7_4 = uilabel(app.Panel);
            app.text7_4.Tag = 'text7';
            app.text7_4.Text = 'Shear-Drift Plots';
            app.text7_5 = uilabel(app.Panel);
            app.text7_5.Tag = 'text7';
            app.text7_5.Text = 'General Plots';
            app.CB13 = uicheckbox(app.Panel);
            app.CB13.ValueChangedFcn = createCallbackFcn(app, @CB13ValueChanged, true);
            app.CB13.Enable = 'off';
            app.CB13.Text = 'EDP Fragility';
            app.edit100 = uieditfield(app.Panel, 'text');
            app.edit100.Editable = 'off';
            app.CB14 = uicheckbox(app.Panel);
            app.CB14.ValueChangedFcn = createCallbackFcn(app, @CB14ValueChanged, true);
            app.CB14.Enable = 'off';
            app.CB14.Text = 'EDP vs Realization';
            app.EDPtype = uidropdown(app.Panel);
            app.EDPtype.Items = {'SDR', 'RDR', 'PFA', 'PFV', 'SSF'};
            app.EDPtype.ItemsData = {'1', '2', '3', '4', '5'};
            app.EDPtype.Enable = 'off';
            app.EDPtype.Value = '1';
            app.EDPtype2 = uidropdown(app.Panel);
            app.EDPtype2.Items = {'SDR', 'RDR', 'PFA'};
            app.EDPtype2.ItemsData = {'1', '2', '3'};
            app.EDPtype2.Enable = 'off';
            app.EDPtype2.Value = '1';
            app.EDPtype3 = uidropdown(app.Panel);
            app.EDPtype3.Items = {'SDR', 'RDR', 'PFA'};
            app.EDPtype3.ItemsData = {'1', '2', '3'};
            app.EDPtype3.Enable = 'off';
            app.EDPtype3.Value = '1';
            app.EDPtype4 = uidropdown(app.Panel);
            app.EDPtype4.Items = {'SDR', 'RDR', 'PFA'};
            app.EDPtype4.ItemsData = {'1', '2', '3'};
            app.EDPtype4.Enable = 'off';
            app.EDPtype4.Value = '1';
            app.text7_6 = uilabel(app.Panel);
            app.text7_6.Tag = 'text7';
            app.text7_6.Text = 'Realization';
            app.editRi = uieditfield(app.Panel, 'numeric');
            app.editRi.Limits = [1 Inf];
            app.editRi.ValueDisplayFormat = '%.0f';
            app.editRi.Enable = 'off';
            app.editRi.Value = 1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Visualize_Options_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end